classdef SqueakPeekStudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SqueakPeekStudioUIFigure        matlab.ui.Figure
        ButtonsMenuPanel                matlab.ui.container.Panel
        ButtonsMenuGrid                 matlab.ui.container.GridLayout
        VisualizationButton             matlab.ui.control.Button
        MetricsButton                   matlab.ui.control.Button
        InfoButton                      matlab.ui.control.Button
        SettingsButton                  matlab.ui.control.Button
        VideoSyncButton                 matlab.ui.control.Button
        LabelEditButton                 matlab.ui.control.Button
        DetectionButton                 matlab.ui.control.Button
        DataInputButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataInputTab                    matlab.ui.container.Tab
        DataInputPanel                  matlab.ui.container.Panel
        DataInputGrid                   matlab.ui.container.GridLayout
        BatchModePanel                  matlab.ui.container.Panel
        BatchModeGridGrid               matlab.ui.container.GridLayout
        SelectnEditField                matlab.ui.control.NumericEditField
        SelectnEditFieldLabel           matlab.ui.control.Label
        VideoBatchFound                 matlab.ui.control.Label
        ReferenceLabelBatchFound        matlab.ui.control.Label
        LabelBatchFound                 matlab.ui.control.Label
        USVBatchFound                   matlab.ui.control.Label
        SelectUSVFieldBatch             matlab.ui.control.EditField
        SelectVideoFieldBatch           matlab.ui.control.EditField
        SelectReferenceLabelFieldBatch  matlab.ui.control.EditField
        SelectLabelFieldBatch           matlab.ui.control.EditField
        SelectVideomp4ButtonBatch       matlab.ui.control.Button
        SelectReferenceLabeltxtButtonBatch  matlab.ui.control.Button
        SelectLabeltxtButtonBatch       matlab.ui.control.Button
        SelectUSVButtonBatch            matlab.ui.control.Button
        SingleModePanel                 matlab.ui.container.Panel
        SingleModeGridGrid              matlab.ui.container.GridLayout
        SelectReferenceLabelButtonSingle  matlab.ui.control.Button
        SelectVideoButtonSingle         matlab.ui.control.Button
        SelectVideoFieldSingle          matlab.ui.control.EditField
        SelectReferenceLabelFieldSingle  matlab.ui.control.EditField
        SelectLabelFieldSingle          matlab.ui.control.EditField
        SelectLabelButtonSingle         matlab.ui.control.Button
        SelectUSVFieldSingle            matlab.ui.control.EditField
        SelectUSVButtonSingle           matlab.ui.control.Button
        SingleBatchModeButton           matlab.ui.control.Button
        LoadFilesButton                 matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        VisualizationPanel              matlab.ui.container.Panel
        VisualizationGrid               matlab.ui.container.GridLayout
        PlotButton                      matlab.ui.control.Button
        SegmentLengthsEditField         matlab.ui.control.NumericEditField
        SegmentLengthsEditFieldLabel    matlab.ui.control.Label
        SegmentStartsEditField          matlab.ui.control.NumericEditField
        SegmentStartsEditFieldLabel     matlab.ui.control.Label
        SonifyButton                    matlab.ui.control.Button
        SegmentMoveForward              matlab.ui.control.Button
        SegmentMoveBackward             matlab.ui.control.Button
        SpectrogramPlot                 matlab.ui.container.Panel
        SpectrogramGrid                 matlab.ui.container.GridLayout
        SpectrogramUIAxes               matlab.ui.control.UIAxes
        WaveformPlot                    matlab.ui.container.Panel
        WaveformGrid                    matlab.ui.container.GridLayout
        WaveformUIAxes                  matlab.ui.control.UIAxes
        MetricsTab                      matlab.ui.container.Tab
        ExportPanel                     matlab.ui.container.Panel
        ExportGrid                      matlab.ui.container.GridLayout
        DetectionmetricsLabel           matlab.ui.control.Label
        DetectedLabelsMetricsEditField  matlab.ui.control.NumericEditField
        DetectedLabelsEditField_5Label  matlab.ui.control.Label
        F1ScoreMetricsEditField         matlab.ui.control.NumericEditField
        F1ScoreEditFieldLabel_2         matlab.ui.control.Label
        RecallMetricsEditField          matlab.ui.control.NumericEditField
        RecallEditFieldLabel_2          matlab.ui.control.Label
        PrecisionMetricsEditField       matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel_2       matlab.ui.control.Label
        FalseNegativesMetricsEditField  matlab.ui.control.NumericEditField
        FalseNegativesEditFieldLabel_2  matlab.ui.control.Label
        TruePositivesMetricsEditField   matlab.ui.control.NumericEditField
        DetectedLabelsEditField_2Label_4  matlab.ui.control.Label
        ReferenceLabelsMetricsEditField  matlab.ui.control.NumericEditField
        DetectedLabelsEditField_2Label_3  matlab.ui.control.Label
        ReloadMetricsButton             matlab.ui.control.Button
        DetectionTab                    matlab.ui.container.Tab
        DetectionPanel                  matlab.ui.container.Panel
        DetectionGrid                   matlab.ui.container.GridLayout
        UsedPostProcessingListBox       matlab.ui.control.ListBox
        PostProcessingLabel             matlab.ui.control.Label
        SelectDetectorsExportFolderEditField  matlab.ui.control.EditField
        SelectDetectorsExportFolder     matlab.ui.control.Button
        UsedDetectorsListBox            matlab.ui.control.ListBox
        DetectorsListBoxLabel           matlab.ui.control.Label
        RunDetectorsButton              matlab.ui.control.Button
        LabelEditTab                    matlab.ui.container.Tab
        LabelEditPanel                  matlab.ui.container.Panel
        LabelEditGrid                   matlab.ui.container.GridLayout
        ExportLabelsButton              matlab.ui.control.Button
        LabelClassificationEditField    matlab.ui.control.EditField
        LabelClassificationEditFieldLabel  matlab.ui.control.Label
        LoadLabelEditButton             matlab.ui.control.Button
        LabelEditAllLabels              matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        LabelEditCurrentLabel           matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        LabelEditMoveForward            matlab.ui.control.Button
        LabelEditMoveBackward           matlab.ui.control.Button
        RejectButton                    matlab.ui.control.Button
        AcceptButton                    matlab.ui.control.Button
        LabelEditSpectrogramUIAxes      matlab.ui.control.UIAxes
        VideoSyncTab                    matlab.ui.container.Tab
        VideoSyncPanel                  matlab.ui.container.Panel
        VideoSyncGrid                   matlab.ui.container.GridLayout
        ExportLabelsButton_2            matlab.ui.control.Button
        SelectUSVFieldSingle_2          matlab.ui.control.EditField
        SelectUSVButtonSingle_2         matlab.ui.control.Button
        SelectDetectorsExportFolderEditField_2  matlab.ui.control.EditField
        SelectDetectorsExportFolder_2   matlab.ui.control.Button
        sLabel                          matlab.ui.control.Label
        SynchronizationTimeinVideoEditField  matlab.ui.control.NumericEditField
        SynchronizationTimeinVideoEditFieldLabel  matlab.ui.control.Label
        SelectVideoButtonSingle_2       matlab.ui.control.Button
        SelectVideoFieldSingle_2        matlab.ui.control.EditField
        SettingsTab                     matlab.ui.container.Tab
        SettingsMenuPanel               matlab.ui.container.Panel
        SettingsMenuGrid                matlab.ui.container.GridLayout
        SettingsText                    matlab.ui.control.Label
        SettingsDropDown                matlab.ui.control.DropDown
        SettingsDropDownLabel           matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        SettingsGrid                    matlab.ui.container.GridLayout
        SettingsInsideTab               matlab.ui.container.TabGroup
        DataInputSettingsTab            matlab.ui.container.Tab
        DataInputSettingsGrid           matlab.ui.container.GridLayout
        SingleBatchModeButton_2         matlab.ui.control.Button
        DetectormodeLabel               matlab.ui.control.Label
        VisualizationSettingsTab        matlab.ui.container.Tab
        VisualizationSettingsGrid       matlab.ui.container.GridLayout
        ReferenceLabelColorDropDown     matlab.ui.control.DropDown
        LabelColorDropDown_2Label       matlab.ui.control.Label
        LabelColorDropDown              matlab.ui.control.DropDown
        LabelColorDropDownLabel         matlab.ui.control.Label
        SpectrogramColormapDropDown     matlab.ui.control.DropDown
        SpectrogramColormapDropDownLabel  matlab.ui.control.Label
        ShowReferenceLabelsCheckBox     matlab.ui.control.CheckBox
        ShowLabelsCheckBox              matlab.ui.control.CheckBox
        kHzLabel_2                      matlab.ui.control.Label
        kHzLabel                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SpectrogramMaximumFrequencyEditField  matlab.ui.control.NumericEditField
        SpectrogramMaximumFrequencyEditFieldLabel  matlab.ui.control.Label
        SpectrogramMinimumFrequencyEditField  matlab.ui.control.NumericEditField
        SpectrogramMinimumFrequencyEditFieldLabel  matlab.ui.control.Label
        SpectrogramWindowOverlapEditField  matlab.ui.control.NumericEditField
        SpectrogramWindowOverlapEditFieldLabel  matlab.ui.control.Label
        SpectrogramWindowSizeEditField  matlab.ui.control.NumericEditField
        SpectrogramWindowSizeEditFieldLabel  matlab.ui.control.Label
        ShowLoadingDialogueCheckBox     matlab.ui.control.CheckBox
        DetectionSettingsTab            matlab.ui.container.Tab
        DetectionSettingsGrid           matlab.ui.container.GridLayout
        LabelEditSettingsTab            matlab.ui.container.Tab
        LabelEditSettingsGrid           matlab.ui.container.GridLayout
        SpectrogramLEMinimumFrequencyEditField  matlab.ui.control.NumericEditField
        SpectrogramMinimumFrequencyEditField_2Label  matlab.ui.control.Label
        SpectrogramLEWindowSizeEditField  matlab.ui.control.NumericEditField
        SpectrogramWindowSizeEditField_2Label  matlab.ui.control.Label
        SpectrogramLEMaximumFrequencyEditField  matlab.ui.control.NumericEditField
        SpectrogramMaximumFrequencyEditField_2Label  matlab.ui.control.Label
        SpectrogramLEWindowOverlapEditField  matlab.ui.control.NumericEditField
        SpectrogramWindowOverlapEditField_2Label  matlab.ui.control.Label
        VideoSyncSettingsTab            matlab.ui.container.Tab
        VideoSyncSettingsGrid           matlab.ui.container.GridLayout
        ExportSettingsTab               matlab.ui.container.Tab
        ExportSettingsGrid              matlab.ui.container.GridLayout
        OtherSettingsTab                matlab.ui.container.Tab
        OtherSettingsGrid               matlab.ui.container.GridLayout
        ThemeDropDown                   matlab.ui.control.DropDown
        ThemeDropDownLabel              matlab.ui.control.Label
        LoadSettingsButton              matlab.ui.control.Button
        SaveSettingsButton              matlab.ui.control.Button
        InfoTab                         matlab.ui.container.Tab
        InfoPanel                       matlab.ui.container.Panel
        InfoGrid                        matlab.ui.container.GridLayout
        CTULogo                         matlab.ui.control.Image
        NIMHLogo                        matlab.ui.control.Image
        AuthorEmailHyperlink            matlab.ui.control.Hyperlink
        AppVersion                      matlab.ui.control.Label
        MatlabVersion                   matlab.ui.control.Label
        ApplicationVersionLabel         matlab.ui.control.Label
        MatlabVersionLabel              matlab.ui.control.Label
        OpenSourceCodeButton            matlab.ui.control.Button
        OpenMastersThesisButton         matlab.ui.control.Button
        OpenDocumentationButton         matlab.ui.control.Button
        SupervisorDepartmentLabel       matlab.ui.control.Label
        AuthorDepartmentLabel           matlab.ui.control.Label
        YearLabel                       matlab.ui.control.Label
        AcademicYearLabel               matlab.ui.control.Label
        SupervisorNameLabel             matlab.ui.control.Label
        SupervisorLabel                 matlab.ui.control.Label
        AuthorNameLabel                 matlab.ui.control.Label
        AuthorLabel                     matlab.ui.control.Label
        Text3Label                      matlab.ui.control.Label
        Text2Label                      matlab.ui.control.Label
        Text1Label                      matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        NameOfAppLabel                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end


    properties (Access = public)


        Theme % Description

        % Different Components
        ButtonsMenu
        ButtonsOther
        Grids
        Labels

        InputFiles = struct( ...
            'USV', {}, ...
            'fs', {}, ...
            'Label', {}, ...
            'ReferenceLabel', {}, ...
            'Video', {}, ...
            'USVPath', {},...
            'LabelPath', {},...
            'ReferenceLabelPath', {},...
            'VideoPath', {}...
            );
        CurrentInputFile = struct( ...
            'USV', {}, ...
            'fs', {}, ...
            'Label', {}, ...
            'ReferenceLabel', {}, ...
            'Video', {}, ...
            'USVPath', {},...
            'LabelPath', {},...
            'ReferenceLabelPath', {},...
            'VideoPath', {}...
            );
        CurrentInputFile_AR = struct( ...
            'USV', {}, ...
            'fs', {}, ...
            'Label', {}, ...
            'ReferenceLabel', {}, ...
            'Video', {}, ...
            'USVPath', {},...
            'LabelPath', {},...
            'ReferenceLabelPath', {},...
            'VideoPath', {}...
            );

        BatchFilesInfo = struct('USVFiles',{},'LabelFiles',{},'ReferenceLabelFiles',{},'VideoFiles',{},'numUSVs', {0}, 'numLabels', {0}, 'numReferenceLabels', {0}, 'numVideos', {0});

    end

    properties (Access = public)
        APP_VERSION = "1.2.2025.5.1"
        AppSettings
    end

    methods (Access = private)

        function applyTheme(app, themeStruct)
            app.Theme = themeStruct;
            app.ThemeDropDown.Value = app.AppSettings.Other.ThemeName;            % update dropdown
            fig = app.SqueakPeekStudioUIFigure;
            fig.Color = themeStruct.Background;

            for i = 1:numel(app.ButtonsMenu)
                app.ButtonsMenu(i).BackgroundColor = themeStruct.Background;
                app.ButtonsMenu(i).FontColor = themeStruct.Text;
            end

            for i = 1:numel(app.ButtonsOther)
                btn = app.ButtonsOther(i);
                if isstruct(btn.UserData) && isfield(btn.UserData, "Style") && strcmp(btn.UserData.Style, "Primary")
                    btn.BackgroundColor = themeStruct.Primary;
                    btn.FontColor = themeStruct.Text;
                elseif isstruct(btn.UserData) && isfield(btn.UserData, "Style") && strcmp(btn.UserData.Style, "Accent")
                    btn.BackgroundColor = themeStruct.Accent;
                    btn.FontColor = themeStruct.Text;
                else
                    btn.BackgroundColor = themeStruct.Background;
                    btn.FontColor = themeStruct.Text;
                end

            end


            for i = 1:numel(app.Grids)
                app.Grids(i).BackgroundColor = themeStruct.Background;
            end
            for i = 1:numel(app.Labels)
                app.Labels(i).FontColor = themeStruct.Text;
            end


            app.ButtonsMenuGrid.BackgroundColor = themeStruct.Primary;
        end
        function InitAppPaths(app) %#ok<MANU>
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'src')));
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'themes')));
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'settings')));
        end
        function InitAppSettings(app)
            app.AppSettings = struct( ...
                "DataInput", struct( ...
                "DefaultUSVFieldSingle", "/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/single/LPS-SI2homo-mH02-I04-USV_denoise.wav", ...
                "DefaultLabelFieldSingle", "/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/single/LPS-SI2homo-mH02-I04-USV_detected.txt", ...
                "DefaultReferenceLabelFieldSingle", "/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/single/LPS-SI2homo-mH02-I04-USV-IVojt.txt", ...
                "DefaultVideoFieldSingle","/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/single/LPS-SI2homo-mH02-I04-USV_sonifikace.mov", ...
                "BatchMode", false, ...
                "DefaultUSVFieldBatch", "/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/batch/USVs/", ...
                "DefaultLabelFieldBatch", "/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/batch/labels/", ...
                "DefaultReferenceLabelFieldBatch", "/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/batch/referenceLabels/", ...
                "DefaultVideoFieldBatch","/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/example/batch/videos/"...
                ),...
                "Visualization", struct( ...
                "ShowLoadingDialog", false, ...
                "SegmentStartSeconds", 60, ...
                "SegmentLengthSeconds", 1, ...
                "SpectrogramWindow", 1024, ...
                "SpectrogramOverlap", 512, ...
                "SpectrogramMinFrequency", 40, ...
                "SpectrogramMaxFrequency", 120, ...
                "SpectrogramColormap","parula", ...
                "ShowReferenceLabels", true, ...
                "ShowLabels", false, ...
                "ReferenceLabelColor", "white", ...
                "LabelColor", "cyan" ...
                ), ...
                "LabelEdit", struct( ...
                "SpectrogramWindow", 1024, ...
                "SpectrogramOverlap", 512, ...
                "SpectrogramMinFrequency", 40, ...
                "SpectrogramMaxFrequency", 120, ...
                "SpectrogramColormap","parula" ...
                ), ...
                "Detection", struct( ...
                "ExportPath", "/Users/gazda/Documents/CTU/Masters/Masters thesis/RoadmapToIng/Squeak-Peak-Studio/data/export/", ...
                "MinDuration", 0.01), ...
                "Export", struct("ExportPath", ""), ...
                "Other", struct("ThemeName", "Light","DefaultSettingsFile", "") ...
                );
        end

        function InitComponents(app)
            % Initialize Buttons for switching with Button Menu
            app.DataInputButton.UserData = app.DataInputTab;
            app.VisualizationButton.UserData = app.VisualizationTab;
            app.DetectionButton.UserData = app.DetectionTab;
            app.LabelEditButton.UserData = app.LabelEditTab;
            app.VideoSyncButton.UserData = app.VideoSyncTab;
            app.MetricsButton.UserData = app.MetricsTab;
            app.SettingsButton.UserData = app.SettingsTab;
            app.InfoButton.UserData = app.InfoTab;

            % define component groups
            app.ButtonsMenu = [app.DataInputButton, app.VisualizationButton, app.DetectionButton, ...
                app.LabelEditButton, app.VideoSyncButton, app.MetricsButton, ...
                app.SettingsButton, app.InfoButton];

            allButtons = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uibutton');
            app.ButtonsOther = setdiff(allButtons, app.ButtonsMenu);

            app.Grids = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uigridlayout');
            app.Labels = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uilabel');
        end

        function SetThemeRules(app) % currently only for buttons
            app.LoadFilesButton.UserData.Style = "Accent";
            app.RunDetectorsButton.UserData.Style = "Accent";
            app.ReloadMetricsButton.UserData.Style = "Accent";
            app.SaveSettingsButton.UserData.Style = "Accent";
            app.OpenDocumentationButton.UserData.Style = "Primary";
            app.OpenSourceCodeButton.UserData.Style = "Primary";
            app.OpenMastersThesisButton.UserData.Style = "Accent";
        end

        function ApplyAllAppSettings(app)
            % OTHER
            %THEME
            themeName = app.AppSettings.Other.ThemeName;
            applyTheme(app, ThemeColors.get(themeName));

            % INPUT
            % SINGLE PATHS
            app.SelectUSVFieldSingle.Value = app.AppSettings.DataInput.DefaultUSVFieldSingle;
            app.SelectLabelFieldSingle.Value = app.AppSettings.DataInput.DefaultLabelFieldSingle;
            app.SelectReferenceLabelFieldSingle.Value = app.AppSettings.DataInput.DefaultReferenceLabelFieldSingle;
            app.SelectVideoFieldSingle.Value = app.AppSettings.DataInput.DefaultVideoFieldSingle;
            % BATCH PATHS
            app.SelectUSVFieldBatch.Value = app.AppSettings.DataInput.DefaultUSVFieldBatch;
            app.SelectLabelFieldBatch.Value = app.AppSettings.DataInput.DefaultLabelFieldBatch;
            app.SelectReferenceLabelFieldBatch.Value = app.AppSettings.DataInput.DefaultReferenceLabelFieldBatch;
            app.SelectVideoFieldBatch.Value = app.AppSettings.DataInput.DefaultVideoFieldBatch;
            % update file count
            % --- Check if folders exist before counting
            app.checkFileCount("USVs");
            app.checkFileCount("Labels");
            app.checkFileCount("ReferenceLabels");
            app.checkFileCount("Videos");

            % SINGLE / BATCH
            btn = app.SingleBatchModeButton;

            if app.AppSettings.DataInput.BatchMode == true % single
                btn.Text = 'Batch Mode';
                btn.BackgroundColor = app.Theme.Accent;

                app.SingleModePanel.Enable = "off";
                app.BatchModePanel.Enable = "on";
            else
                btn.Text = 'Single Mode';
                btn.BackgroundColor = app.Theme.Background;
                app.SingleModePanel.Enable = "on";
                app.BatchModePanel.Enable = "off";
            end


            % VISUALISATION
            app.SegmentStartsEditField.Value = app.AppSettings.Visualization.SegmentStartSeconds;
            app.SegmentLengthsEditField.Value = app.AppSettings.Visualization.SegmentLengthSeconds;
            app.ShowLoadingDialogueCheckBox.Value = app.AppSettings.Visualization.ShowLoadingDialog;

            app.SpectrogramWindowSizeEditField.Value = app.AppSettings.Visualization.SpectrogramWindow;
            app.SpectrogramWindowOverlapEditField.Value = app.AppSettings.Visualization.SpectrogramOverlap;
            app.SpectrogramMinimumFrequencyEditField.Value = app.AppSettings.Visualization.SpectrogramMinFrequency;
            app.SpectrogramMaximumFrequencyEditField.Value = app.AppSettings.Visualization.SpectrogramMaxFrequency;

            app.SpectrogramColormapDropDown.Value = app.AppSettings.Visualization.SpectrogramColormap;
            app.ShowLabelsCheckBox.Value = app.AppSettings.Visualization.ShowLabels;
            app.ShowReferenceLabelsCheckBox.Value = app.AppSettings.Visualization.ShowReferenceLabels;

            app.LabelColorDropDown.Value = app.AppSettings.Visualization.LabelColor;
            app.ReferenceLabelColorDropDown.Value = app.AppSettings.Visualization.ReferenceLabelColor;
            
            % DETECTION
            app.SelectDetectorsExportFolderEditField.Value = app.AppSettings.Detection.ExportPath;
        end

        function loadSettingsFromFile(app, filepath)
            if nargin < 2
                [file, path] = uigetfile('*.json', 'Load Settings');
                if isequal(file, 0)
                    return; % User canceled
                end
                filepath = fullfile(path, file);
            end

            fid = fopen(filepath, 'r');
            if fid == -1
                uialert(app.SqueakPeekStudioUIFigure, 'Could not open file for reading.', 'File Error');
                return;
            end

            raw = fread(fid, '*char')';
            fclose(fid);

            try
                settings = jsondecode(raw);
                app.AppSettings = settings;
                ApplyAllAppSettings(app);  % Re-apply after loading
            catch ME
                uialert(app.SqueakPeekStudioUIFigure, sprintf('Error reading settings:\n%s', ME.message), 'JSON Error');
            end
        end

        function loadDefaultSettingsIfExists(app)
            preloadName = app.AppSettings.Other.DefaultSettingsFile;
            appFolder = fileparts(mfilename('fullpath'));
            settingsPath = fullfile(appFolder, 'settings', preloadName);

            if exist(settingsPath, 'file') == 2
                try
                    loadSettingsFromFile(app, settingsPath);
                    disp("Loaded default settings from: " + settingsPath);
                catch err
                    warning('%s: %s', err.identifier, err.message);
                end
            else
                warning("Default settings file not found at: %s", settingsPath);
            end
        end


        function [files, numFiles] = getFilesFromFolder(~,folderPath, fileExtension)
            % getFilesFromFolder - Lists files with the given extension in a folder.
            %
            % Inputs:
            %   folderPath     - string or char, path to the folder
            %   fileExtension  - string or char, e.g. '*.wav', '*.txt', '*.mov'
            %
            % Outputs:
            %   files     - struct array from dir()
            %   numFiles  - number of files found

            if nargin < 2
                error('You must provide both folderPath and fileExtension, e.g., ''*.wav''');
            end

            files = dir(fullfile(folderPath, fileExtension));
            numFiles = numel(files);

            %fprintf("Found %d %s files in:\n%s\n", numFiles, fileExtension, folderPath);
            for i = 1:numFiles
                fullPath = fullfile(files(i).folder, files(i).name);
             %   fprintf("%s\n", fullPath);
            end
        end


        function count = checkFileCount(app, type)
            try
                switch type
                    case "USVs"
                        [files, count] = getFilesFromFolder(app, app.SelectUSVFieldBatch.Value, '*.wav');
                        app.BatchFilesInfo(1).USVFiles = files;
                        app.BatchFilesInfo(1).numUSVs = count;
                        app.USVBatchFound.Text = sprintf("(%d)", count);
                    case "Labels"
                        [files, count] = getFilesFromFolder(app, app.SelectLabelFieldBatch.Value, '*.txt');
                        app.BatchFilesInfo(1).LabelFiles = files;
                        app.BatchFilesInfo(1).numLabels = count;
                        app.LabelBatchFound.Text = sprintf("(%d)", count);
                    case "ReferenceLabels"
                        [files, count] = getFilesFromFolder(app, app.SelectReferenceLabelFieldBatch.Value, '*.txt');
                        app.BatchFilesInfo(1).ReferenceLabelFiles = files;
                        app.BatchFilesInfo(1).numReferenceLabels = count;
                        app.ReferenceLabelBatchFound.Text = sprintf("(%d)", count);
                    case "Videos"
                        [files, count] = getFilesFromFolder(app, app.SelectVideoFieldBatch.Value, '*.mov');
                        app.BatchFilesInfo(1).VideoFiles = files;
                        app.BatchFilesInfo(1).numVideos = count;
                        app.VideoBatchFound.Text = sprintf("(%d)", count);
                    otherwise
                        warning("Wrong input for function checkFileCount");
                        count = 0;
                end
            catch ME
                warning("Error in checkFileCount for %s: %s", type, ME.message);
                count = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            disp("Welcome to Squeak Peek Studio by Antonín Gazda");
            fprintf("Version %s\n",app.APP_VERSION);
            app.AppVersion.Text = app.APP_VERSION;
            InitAppPaths(app);
            InitAppSettings(app);
            loadDefaultSettingsIfExists(app);

            disp(app.AppSettings)

            InitComponents(app);  % define Components
            SetThemeRules(app);   % set Primary,Accent,Background etc.
            ApplyAllAppSettings(app);

            % Select default tab
            app.HandleMenuButtons(struct("Source", app.DataInputButton));


        end

        % Button pushed function: DataInputButton, DetectionButton, 
        % ...and 6 other components
        function HandleMenuButtons(app, event)
            clickedButton = event.Source;
            % Reset all buttons to the inactive color

            for b = app.ButtonsMenu
                b.BackgroundColor = app.Theme.Background;
            end

            % Highlight the clicked button
            clickedButton.BackgroundColor = app.Theme.Accent;

            if clickedButton == app.SettingsButton && ...
                    app.TabGroup.SelectedTab ~= app.SettingsTab && ...
                    app.TabGroup.SelectedTab ~= app.InfoTab

                lastTab = app.TabGroup.SelectedTab;

                %disp("Went into settings");
                %fprintf("lastTab = %s\n", lastTab.Title);
                app.SettingsDropDown.Value = lastTab.Title;
                SettingsDropDownValueChanged(app, struct('Value', lastTab.Title));

            end

            % Switch to the tab associated with this button
            app.TabGroup.SelectedTab = clickedButton.UserData;
        end

        % Button pushed function: SingleBatchModeButton, 
        % ...and 1 other component
        function SingleBatchModeButtonPushed(app, event)
            btn = event.Source;
            lastState = app.AppSettings.DataInput.BatchMode;
            if lastState == false % single
                % Switch to Batch mode
                btn.Text = 'Batch Mode';
                btn.BackgroundColor = app.Theme.Accent;
                app.AppSettings.DataInput.BatchMode = 1;

                %change text on labels and the files they accept
                app.SingleModePanel.Enable = "off";
                app.BatchModePanel.Enable = "on";
            else
                % Switch to Single mode
                btn.Text = 'Single Mode';
                btn.BackgroundColor = app.Theme.Background;
                app.AppSettings.DataInput.BatchMode = 0;
                app.SingleModePanel.Enable = "on";
                app.BatchModePanel.Enable = "off";
            end


        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            app.InputFiles = struct( ...
                'USV', {}, ...
                'fs', {}, ...
                'Label', {}, ...
                'ReferenceLabel', {}, ...
                'Video', {}, ...
                'USVPath', {},...
                'LabelPath', {},...
                'ReferenceLabelPath', {},...
                'VideoPath', {}...
                );

            d = uiprogressdlg(app.SqueakPeekStudioUIFigure, ...
                'Title', 'Please Wait', ...
                'Message', 'Loading files...', ...
                'Indeterminate', 'on');
            drawnow;

            try
                if app.AppSettings.DataInput.BatchMode == 0 % Single mode
                    [usv, fs] = audioread(app.SelectUSVFieldSingle.Value);
                    label = importLabels(app.SelectLabelFieldSingle.Value, fs);
                    refLabel = importLabels(app.SelectReferenceLabelFieldSingle.Value, fs);
                    video = VideoReader(app.SelectVideoFieldSingle.Value);


                    app.InputFiles(1) = struct( ...
                        'USV', usv, ...
                        'fs', fs, ...
                        'Label', label, ...
                        'ReferenceLabel', refLabel, ...
                        'Video', video, ...
                        'USVPath', app.SelectUSVFieldSingle.Value,...
                        'LabelPath', app.SelectLabelFieldSingle.Value,...
                        'ReferenceLabelPath', app.SelectReferenceLabelFieldSingle.Value,...
                        'VideoPath', app.SelectVideoFieldSingle.Value...
                        );
                    app.CurrentInputFile = app.InputFiles(1);

                else % Batch mode
                    app.checkFileCount("USVs");
                    app.checkFileCount("Labels");
                    app.checkFileCount("ReferenceLabels");
                    app.checkFileCount("Videos");

                    numFiles = min([
                        app.BatchFilesInfo.numUSVs, ...
                        app.BatchFilesInfo.numLabels, ...
                        app.BatchFilesInfo.numReferenceLabels, ...
                        app.BatchFilesInfo.numVideos]);

                    for i = 1:numFiles
                        usvPath = fullfile(app.SelectUSVFieldBatch.Value, app.BatchFilesInfo.USVFiles(i).name);
                        labelPath = fullfile(app.SelectLabelFieldBatch.Value, app.BatchFilesInfo.LabelFiles(i).name);
                        refLabelPath = fullfile(app.SelectReferenceLabelFieldBatch.Value, app.BatchFilesInfo.ReferenceLabelFiles(i).name);
                        videoPath = fullfile(app.SelectVideoFieldBatch.Value, app.BatchFilesInfo.VideoFiles(i).name);

                        [usv, fs] = audioread(usvPath);
                        label = importLabels(labelPath, fs);
                        refLabel = importLabels(refLabelPath, fs);
                        video = VideoReader(videoPath);
                        app.InputFiles(i) = struct( ...
                            'USV', usv, ...
                            'fs', fs, ...
                            'Label', label, ...
                            'ReferenceLabel', refLabel, ...
                            'Video', video, ...
                            'USVPath', usvPath,...
                            'LabelPath', labelPath,...
                            'ReferenceLabelPath', refLabelPath,...
                            'VideoPath', videoPath...
                            );

                    end

                    % Pick selected file
                    fileI = app.SelectnEditField.Value;
                    if fileI < 1 || fileI > numFiles
                        error("Selected index %d is out of range [1, %d]", fileI, numFiles);
                    end

                    app.CurrentInputFile = app.InputFiles(fileI);

                end

                app.CurrentInputFile_AR = app.CurrentInputFile;

                d.Message = 'Finished loading files.';
                pause(0.4);

            catch ME
                warning("Load error (%s): %s", ME.identifier, ME.message);
                d.Message = "Loading failed. Check console for errors.";
                pause(1.0);
            end

            if isvalid(d)
                close(d);
            end

            app.HandleMenuButtons(struct('Source', app.VisualizationButton));

            app.PlotButtonPushed();
            app.ReloadMetricsButtonPushed();
        end

        % Button pushed function: OpenDocumentationButton
        function OpenDocumentationButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/docs', '-browser')
        end

        % Button pushed function: OpenMastersThesisButton
        function OpenMastersThesisButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/thesis', '-browser')
        end

        % Button pushed function: OpenSourceCodeButton
        function OpenSourceCodeButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/source', '-browser')
        end

        % Value changed function: SettingsDropDown
        function SettingsDropDownValueChanged(app, event)
            fprintf("Changed to %s",event.Value);
            switch event.Value
                case "Data Input"
                    app.SettingsInsideTab.SelectedTab = app.DataInputSettingsTab;
                case "Visualization"
                    app.SettingsInsideTab.SelectedTab = app.VisualizationSettingsTab;
                case "Detection"
                    app.SettingsInsideTab.SelectedTab = app.DetectionSettingsTab;
                case "Label Edit"
                    app.SettingsInsideTab.SelectedTab = app.LabelEditSettingsTab;
                case "Video Sync"
                    app.SettingsInsideTab.SelectedTab = app.VideoSyncSettingsTab;
                case "Metrics"
                    app.SettingsInsideTab.SelectedTab = app.ExportSettingsTab;
                case "Other"
                    app.SettingsInsideTab.SelectedTab = app.OtherSettingsTab;
                otherwise
                    warning("Unknown dropdown selection: %s", event.Value);
            end
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            themeName = app.ThemeDropDown.Value;
            app.AppSettings.Other.ThemeName = themeName;
            applyTheme(app,ThemeColors.get(themeName));
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            saveSettingsToFile(app);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            loadSettingsFromFile(app);
        end

        % Button pushed function: SelectUSVButtonSingle
        function SelectUSVButtonSinglePushed(app, event)
            [USV_single_filename, USV_single_location] = uigetfile('*.wav');
            app.SelectUSVFieldSingle.Value = fullfile(USV_single_location, USV_single_filename);
            disp("USV Path Updated");
        end

        % Button pushed function: SelectLabelButtonSingle
        function SelectLabelButtonSinglePushed(app, event)
            [Label_single_filename, Label_single_location] = uigetfile('*.txt');
            app.SelectLabelFieldSingle.Value = fullfile(Label_single_location, Label_single_filename);
            disp("Label Path Updated");
        end

        % Button pushed function: SelectReferenceLabelButtonSingle
        function SelectReferenceLabelButtonSinglePushed(app, event)
            [ReferenceLabel_single_filename, ReferenceLabel_single_location] = uigetfile('*.txt');
            app.SelectReferenceLabelFieldSingle.Value = fullfile(ReferenceLabel_single_location, ReferenceLabel_single_filename);
            disp("Reference Path Label Updated");
        end

        % Callback function: SelectVideoButtonSingle, 
        % ...and 1 other component
        function SelectVideoButtonSinglePushed(app, event)
            [Video_single_filename, Video_single_location] = uigetfile('*.mov');
            app.SelectVideoFieldSingle.Value = fullfile(Video_single_location, Video_single_filename);
            disp("Video Path Updated");
        end

        % Button pushed function: SelectUSVButtonBatch
        function SelectUSVButtonBatchPushed(app, event)
            folderPath = uigetdir();
            if isequal(folderPath, 0)
                return; % User canceled
            end
            app.checkFileCount("USVs");
        end

        % Callback function: not associated with a component
        function BREAKButtonPushed(app, event)
            a = 0;
            b = a;
        end

        % Button pushed function: SelectLabeltxtButtonBatch
        function SelectLabeltxtButtonBatchPushed(app, event)
            folderPath = uigetdir();
            if isequal(folderPath, 0)
                return; % User canceled
            end
            app.checkFileCount("Labels");
        end

        % Button pushed function: SelectReferenceLabeltxtButtonBatch
        function SelectReferenceLabeltxtButtonBatchPushed(app, event)
            folderPath = uigetdir();
            if isequal(folderPath, 0)
                return; % User canceled
            end
            app.checkFileCount("ReferenceLabels");
        end

        % Button pushed function: SelectVideomp4ButtonBatch
        function SelectVideomp4ButtonBatchPushed(app, event)
            folderPath = uigetdir();
            if isequal(folderPath, 0)
                return; % User canceled
            end
            app.checkFileCount("Videos");
        end

        % Value changed function: SelectUSVFieldBatch
        function SelectUSVFieldBatchValueChanged(app, event)
            value = app.SelectUSVFieldBatch.Value;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % --- Show loading dialog
            if app.AppSettings.Visualization.ShowLoadingDialog
                d = uiprogressdlg(app.SqueakPeekStudioUIFigure, ...
                    'Title', 'Please Wait', ...
                    'Message', 'Rendering waveform and spectrogram...', ...
                    'Indeterminate', 'on');
                drawnow;
            end

            try
                % --- Extract inputs
                USVVariable = app.CurrentInputFile.USV;
                labelStruct = app.CurrentInputFile.Label;
                referenceLabelStruct = app.CurrentInputFile.ReferenceLabel;
                fs = app.CurrentInputFile.fs;

                % --- Segment timing
                segmentStartSeconds = app.SegmentStartsEditField.Value;
                segmentLengthSeconds = app.SegmentLengthsEditField.Value;
                segmentEndSeconds = segmentStartSeconds+segmentLengthSeconds;

                % --- Index range
                segmentStartIndex = round(segmentStartSeconds * fs) + 1;
                segmentEndIndex   = round((segmentStartSeconds + segmentLengthSeconds) * fs);

                % --- Extract segment and generate time vector
                USVSegment = USVVariable(segmentStartIndex:segmentEndIndex);
                segmentTime = linspace(segmentStartSeconds, ...
                    segmentStartSeconds + segmentLengthSeconds, ...
                    length(USVSegment));

                % === Plot waveform ===
                plot(app.WaveformUIAxes, segmentTime, USVSegment);
                axis(app.WaveformUIAxes, 'tight');
                grid(app.WaveformUIAxes, 'on');
                xlabel(app.WaveformUIAxes, 'Time (s)');
                ylabel(app.WaveformUIAxes, 'Amplitude');
                xlim(app.WaveformUIAxes, [segmentStartSeconds, segmentStartSeconds + segmentLengthSeconds]);

                % === Compute and plot spectrogram ===
                windowSize = app.AppSettings.Visualization.SpectrogramWindow;
                overlap = app.AppSettings.Visualization.SpectrogramOverlap;

                [~, f, t, p] = spectrogram(USVSegment, windowSize, overlap, [], fs, 'yaxis');
                f_kHz = f / 1000;
                p_dB = 10 * log10(abs(p) + eps);  % avoid log(0)

                imagesc(app.SpectrogramUIAxes, t + segmentStartSeconds, f_kHz, p_dB);
                axis(app.SpectrogramUIAxes, 'xy');
                xlabel(app.SpectrogramUIAxes, 'Time (s)');
                ylabel(app.SpectrogramUIAxes, 'Frequency (kHz)');
                colormap(app.SpectrogramUIAxes, app.AppSettings.Visualization.SpectrogramColormap);
                ylim(app.SpectrogramUIAxes, [app.AppSettings.Visualization.SpectrogramMinFrequency, ...
                    app.AppSettings.Visualization.SpectrogramMaxFrequency]);
                xlim(app.SpectrogramUIAxes, [segmentStartSeconds, segmentStartSeconds + segmentLengthSeconds]);

                % === Set identical x-ticks ===
                xtickVals = linspace(segmentStartSeconds, segmentStartSeconds + segmentLengthSeconds, 11);
                set(app.WaveformUIAxes, 'XTick', xtickVals);
                set(app.SpectrogramUIAxes, 'XTick', xtickVals);


                %%%%% LABELS %%%%%
                minFreq = app.AppSettings.Visualization.SpectrogramMinFrequency;
                maxFreq = app.AppSettings.Visualization.SpectrogramMaxFrequency;
                showLabels = app.AppSettings.Visualization.ShowLabels;
                showRefLabels = app.AppSettings.Visualization.ShowReferenceLabels;

                % Clear previous label overlays
                hold(app.SpectrogramUIAxes, 'on');
                delete(findall(app.SpectrogramUIAxes, 'Tag', 'LabelOverlay'));

                % Plot Detected Labels
                labelsColor = app.AppSettings.Visualization.LabelColor;
                referenceLabelsColor = app.AppSettings.Visualization.ReferenceLabelColor;

                if showLabels
                    for i = 1:length(app.CurrentInputFile.Label)
                        L = app.CurrentInputFile.Label(i);
                        if L.StartTime >= segmentStartSeconds && L.EndTime <= segmentEndSeconds
                            x1 = L.StartTime; x2 = L.EndTime;
                            % Vertical lines
                            line(app.SpectrogramUIAxes, [x1 x1], [minFreq maxFreq], ...
                                'Color', labelsColor, 'LineWidth', 1.2, 'LineStyle', '--', 'Tag', 'LabelOverlay');
                            line(app.SpectrogramUIAxes, [x2 x2], [minFreq maxFreq], ...
                                'Color', labelsColor, 'LineWidth', 1.2, 'LineStyle', '-.', 'Tag', 'LabelOverlay');
                            % Text
                            if ~strcmp(L.Label, "None")
                                text(app.SpectrogramUIAxes, (x1 + x2) / 2, maxFreq - 3, L.Label, ...
                                    'Color', labelsColor, 'BackgroundColor', 'black', 'FontSize', 12, ...
                                    'HorizontalAlignment', 'center', 'Tag', 'LabelOverlay');
                            end
                        end
                    end
                end

                % Plot Reference Labels
                if showRefLabels
                    for i = 1:length(app.CurrentInputFile.ReferenceLabel)
                        R = app.CurrentInputFile.ReferenceLabel(i);
                        if R.StartTime >= segmentStartSeconds && R.EndTime <= segmentEndSeconds
                            x1 = R.StartTime; x2 = R.EndTime;
                            % Vertical lines
                            line(app.SpectrogramUIAxes, [x1 x1], [minFreq maxFreq], ...
                                'Color', referenceLabelsColor, 'LineWidth', 1.2, 'LineStyle', '--', 'Tag', 'LabelOverlay');
                            line(app.SpectrogramUIAxes, [x2 x2], [minFreq maxFreq], ...
                                'Color', referenceLabelsColor, 'LineWidth', 1.2, 'LineStyle', '-.', 'Tag', 'LabelOverlay');
                            % Text
                            if ~strcmp(R.Label, "None")
                                text(app.SpectrogramUIAxes, (x1 + x2) / 2, maxFreq - 8, R.Label, ...
                                    'Color', referenceLabelsColor, 'BackgroundColor', 'black', 'FontSize', 12, ...
                                    'HorizontalAlignment', 'center', 'Tag', 'LabelOverlay');
                            end
                        end
                    end
                end

                hold(app.SpectrogramUIAxes, 'off');

            catch ME
                warning(ME.identifier, 'Visualization error: %s', ME.message);
            end


            % --- Close loading dialog
            if exist('d', 'var') && isvalid(d)
                pause(0.2);
                close(d);
            end


        end

        % Value changed function: SegmentStartsEditField
        function SegmentStartsEditFieldValueChanged(app, event)
            value = app.SegmentStartsEditField.Value;

            % update in settings
            app.AppSettings.Visualization.SegmentStartSeconds = value;
            % todo: needs to replot...
        end

        % Value changed function: SegmentLengthsEditField
        function SegmentLengthsEditFieldValueChanged(app, event)
            value = app.SegmentLengthsEditField.Value;

            % update in settings
            app.AppSettings.Visualization.SegmentLengthSeconds = value;
            % todo: needs to replot...
        end

        % Button pushed function: SegmentMoveBackward
        function SegmentMoveBackwardButtonPushed(app, event)
            app.AppSettings.Visualization.SegmentStartSeconds = app.AppSettings.Visualization.SegmentStartSeconds-app.AppSettings.Visualization.SegmentLengthSeconds;
            if app.AppSettings.Visualization.SegmentStartSeconds < 0
                app.AppSettings.Visualization.SegmentStartSeconds = 0;
            end
            app.SegmentStartsEditField.Value = app.AppSettings.Visualization.SegmentStartSeconds;

            % replot
            app.PlotButtonPushed();
        end

        % Button pushed function: SegmentMoveForward
        function SegmentMoveForwardButtonPushed(app, event)
            app.AppSettings.Visualization.SegmentStartSeconds = app.AppSettings.Visualization.SegmentStartSeconds+app.AppSettings.Visualization.SegmentLengthSeconds;
            USVLengthSeconds = length(app.CurrentInputFile.USV) / app.CurrentInputFile.fs;
            if app.AppSettings.Visualization.SegmentStartSeconds > USVLengthSeconds
                app.AppSettings.Visualization.SegmentStartSeconds = USVLengthSeconds-app.AppSettings.Visualization.SegmentLengthSeconds;
            end
            app.SegmentStartsEditField.Value = app.AppSettings.Visualization.SegmentStartSeconds;

            % replot
            app.PlotButtonPushed();
        end

        % Value changed function: ShowLoadingDialogueCheckBox
        function ShowLoadingDialogueCheckBoxValueChanged(app, event)
            value = app.ShowLoadingDialogueCheckBox.Value;

            app.AppSettings.Visualization.ShowLoadingDialog = value;
        end

        % Value changed function: SpectrogramWindowSizeEditField
        function SpectrogramWindowSizeEditFieldValueChanged(app, event)
            value = app.SpectrogramWindowSizeEditField.Value;
            app.AppSettings.Visualization.SpectrogramWindow = value;
        end

        % Value changed function: SpectrogramWindowOverlapEditField
        function SpectrogramWindowOverlapEditFieldValueChanged(app, event)
            value = app.SpectrogramWindowOverlapEditField.Value;
            app.AppSettings.Visualization.SpectrogramOverlap = value;
        end

        % Value changed function: SpectrogramMinimumFrequencyEditField
        function SpectrogramMinimumFrequencyEditFieldValueChanged(app, event)
            value = app.SpectrogramMinimumFrequencyEditField.Value;
            app.AppSettings.Visualization.SpectrogramMinFrequency = value;
        end

        % Value changed function: SpectrogramMaximumFrequencyEditField
        function SpectrogramMaximumFrequencyEditFieldValueChanged(app, event)
            value = app.SpectrogramMaximumFrequencyEditField.Value;
            app.AppSettings.Visualization.SpectrogramMaxFrequency = value;
        end

        % Value changed function: ShowLabelsCheckBox
        function ShowLabelsCheckBoxValueChanged(app, event)
            value = app.ShowLabelsCheckBox.Value;
            app.AppSettings.Visualization.ShowLabels = value;
        end

        % Value changed function: ShowReferenceLabelsCheckBox
        function ShowReferenceLabelsCheckBoxValueChanged(app, event)
            value = app.ShowReferenceLabelsCheckBox.Value;
            app.AppSettings.Visualization.ShowReferenceLabels = value;
        end

        % Button pushed function: RunDetectorsButton
        function RunDetectorsButtonPushed(app, event)
            % Show progress dialog
            d = uiprogressdlg(app.SqueakPeekStudioUIFigure, ...
                'Title', 'Running Detectors', ...
                'Message', 'Preparing...', ...
                'Indeterminate', 'on');

            drawnow;

            selectedDetectors = app.UsedDetectorsListBox.Value;
            selectedPostprocessing = app.UsedPostProcessingListBox.Value;

            if app.AppSettings.DataInput.BatchMode == false % single mode

                fs = app.CurrentInputFile.fs;
                usv = app.CurrentInputFile.USV;
                filename = app.CurrentInputFile.USVPath;

                exportDir = app.AppSettings.Detection.ExportPath;
                [~, baseName, ~] = fileparts(filename);  % Strip extension

                for k = 1:length(selectedDetectors)
                    det = selectedDetectors{k};
                    labelStruct = [];

                    switch det
                        case 'PSD'
                            d.Message = sprintf('Running PSD Detector on:\n%s', filename);
                            drawnow;
                            labelStruct = PSDDetector(usv, fs);

                        case 'BSCD'
                            d.Message = sprintf('Running BSCD Detector on:\n%s', filename);
                            drawnow;
                            labelStruct = BSCDDetector(usv, fs);  % Uncomment when ready

                        case 'RBD'
                            d.Message = sprintf('Running RBD Detector on:\n%s', filename);
                            drawnow;
                            labelStruct = RBD(usv, fs);  % Uncomment when ready
                    end

                    % Apply selected post-processing steps
                    for j = 1:length(selectedPostprocessing)
                        post = selectedPostprocessing{j};
                        switch post
                            case 'Merge Close Labels'
                                labelStruct = mergeCloseLabels(labelStruct, 0.001); % 1 ms
                                % Add more post-processing steps as needed
                            case 'Remove Short Labels'
                                labelStruct = removeShortLabels(labelStruct, 0.005); % 5 ms
                        end
                    end

                    % Export to file
                    exportFile = fullfile(exportDir, [baseName, '_', det, '_detected.txt']);
                    exportLabelsDetector(labelStruct, exportFile);
                end
            end

            % Close dialog
            if isvalid(d)
                close(d);
            end
        end

        % Button pushed function: LoadLabelEditButton
        function LoadLabelEditButtonPushed(app, event)
            % Update maximum label count
            app.LabelEditAllLabels.Value = length(app.CurrentInputFile_AR.Label);

            % Get current label index
            i = app.LabelEditCurrentLabel.Value;

            % Validate index
            if i < 1 || i > length(app.CurrentInputFile_AR.Label)
                warning("Invalid label index selected.");
                return;
            end

            % Get label struct and compute midpoint
            L = app.CurrentInputFile_AR.Label(i);
            midpoint = (L.StartTime + L.EndTime) / 2;
            segmentStartSeconds = midpoint - 0.25;
            segmentEndSeconds = midpoint + 0.25;

            % Clamp if outside signal
            fs = app.CurrentInputFile_AR.fs;
            USV = app.CurrentInputFile_AR.USV;
            totalDuration = length(USV) / fs;

            segmentStartSeconds = max(segmentStartSeconds, 0);
            segmentEndSeconds = min(segmentEndSeconds, totalDuration);
            segmentLengthSeconds = segmentEndSeconds - segmentStartSeconds;

            % Compute indices and extract audio segment
            segmentStartIndex = round(segmentStartSeconds * fs) + 1;
            segmentEndIndex = round(segmentEndSeconds * fs);
            USVSegment = USV(segmentStartIndex:segmentEndIndex);

            % Spectrogram calculation
            windowSize = app.AppSettings.Visualization.SpectrogramWindow;
            overlap = app.AppSettings.Visualization.SpectrogramOverlap;

            [~, f, t, p] = spectrogram(USVSegment, windowSize, overlap, [], fs, 'yaxis');
            f_kHz = f / 1000;
            p_dB = 10 * log10(abs(p) + eps);  % avoid log(0)

            % Plot
            imagesc(app.LabelEditSpectrogramUIAxes, t + segmentStartSeconds, f_kHz, p_dB);
            axis(app.LabelEditSpectrogramUIAxes, 'xy');
            xlabel(app.LabelEditSpectrogramUIAxes, 'Time (s)');
            ylabel(app.LabelEditSpectrogramUIAxes, 'Frequency (kHz)');
            colormap(app.LabelEditSpectrogramUIAxes, app.AppSettings.Visualization.SpectrogramColormap);
            ylim(app.LabelEditSpectrogramUIAxes, [app.AppSettings.Visualization.SpectrogramMinFrequency, ...
                app.AppSettings.Visualization.SpectrogramMaxFrequency]);
            xlim(app.LabelEditSpectrogramUIAxes, [segmentStartSeconds, segmentEndSeconds]);

            % Add label overlay
            hold(app.LabelEditSpectrogramUIAxes, 'on');
            line(app.LabelEditSpectrogramUIAxes, [L.StartTime, L.StartTime], ylim(app.LabelEditSpectrogramUIAxes), ...
                'Color', 'w', 'LineWidth', 1.2, 'LineStyle', '--');
            line(app.LabelEditSpectrogramUIAxes, [L.EndTime, L.EndTime], ylim(app.LabelEditSpectrogramUIAxes), ...
                'Color', 'w', 'LineWidth', 1.2, 'LineStyle', '-.');
            hold(app.LabelEditSpectrogramUIAxes, 'off');

            app.LabelClassificationEditField.Value = L.Label;
        end

        % Value changed function: SpectrogramColormapDropDown
        function SpectrogramColormapDropDownValueChanged(app, event)
            value = app.SpectrogramColormapDropDown.Value;
            app.AppSettings.Visualization.SpectrogramColormap = value;
        end

        % Value changed function: LabelColorDropDown
        function LabelColorDropDownValueChanged(app, event)
            value = app.LabelColorDropDown.Value;
            app.AppSettings.Visualization.LabelColor = value;
        end

        % Value changed function: ReferenceLabelColorDropDown
        function ReferenceLabelColorDropDownValueChanged(app, event)
            value = app.ReferenceLabelColorDropDown.Value;
            app.AppSettings.Visualization.ReferenceLabelColor = value;
        end

        % Value changed function: LabelEditCurrentLabel
        function LabelEditCurrentLabelValueChanged(app, event)
            value = app.LabelEditCurrentLabel.Value;

        end

        % Button pushed function: LabelEditMoveBackward
        function LabelEditMoveBackwardButtonPushed(app, event)
            if app.LabelEditCurrentLabel.Value > 1
                app.LabelEditCurrentLabel.Value = app.LabelEditCurrentLabel.Value -1;
            end
            app.LoadLabelEditButtonPushed();
        end

        % Button pushed function: LabelEditMoveForward
        function LabelEditMoveForwardButtonPushed(app, event)

            if app.LabelEditCurrentLabel.Value < length(app.CurrentInputFile.Label)
                app.LabelEditCurrentLabel.Value = app.LabelEditCurrentLabel.Value +1;
            end
            app.LoadLabelEditButtonPushed();
        end

        % Value changed function: LabelClassificationEditField
        function LabelClassificationEditFieldValueChanged(app, event)
            value = app.LabelClassificationEditField.Value;
            i = app.LabelEditCurrentLabel.Value;
            app.CurrentInputFile_AR.Label(i).Label = value;

        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            app.LabelEditMoveForwardButtonPushed(); % simply go to the next label
        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(app, event)
            i = app.LabelEditCurrentLabel.Value;

            % Delete the label
            app.CurrentInputFile_AR.Label(i) = [];

            % Update total label count
            app.LabelEditAllLabels.Value = length(app.CurrentInputFile_AR.Label);

            % If we're at the end, move back, else move forward
            if i > length(app.CurrentInputFile_AR.Label)
                app.LabelEditCurrentLabel.Value = max(i - 1, 1);
            end

            % Reload the new label
            app.LoadLabelEditButtonPushed();

        end

        % Button pushed function: ExportLabelsButton
        function ExportLabelsButtonPushed(app, event)
            [fileName, folderPath] = uiputfile('*.txt', 'Save Labels As', 'exported_labels.txt');
            if isequal(fileName, 0) || isequal(folderPath, 0)
                return; % User cancelled
            end

            fullPath = fullfile(folderPath, fileName);
            exportLabels(app.CurrentInputFile_AR.Label, fullPath);
        end

        % Button pushed function: SelectDetectorsExportFolder
        function SelectDetectorsExportFolderButtonPushed(app, event)
            folderPath = uigetdir();
            if isequal(folderPath, 0)
                return; % User canceled
            end
            app.SelectDetectorsExportFolderEditField.Value = folderPath;
        end

        % Value changed function: SelectDetectorsExportFolderEditField
        function SelectDetectorsExportFolderEditFieldValueChanged(app, event)
            value = app.SelectDetectorsExportFolderEditField.Value;
            app.AppSettings.Detection.ExportPath = value;
        end

        % Button pushed function: ReloadMetricsButton
        function ReloadMetricsButtonPushed(app, event)
            in = app.CurrentInputFile;
            metrics = compareLabels(in.ReferenceLabelPath,in.LabelPath,in.fs);
        
            app.ReferenceLabelsMetricsEditField.Value = metrics.TotalProvidedLabels;
            app.DetectedLabelsMetricsEditField.Value = metrics.TotalDetectedLabels;
            app.TruePositivesMetricsEditField.Value = metrics.TruePositives;
            app.FalseNegativesMetricsEditField.Value = metrics.FalseNegatives;
            app.PrecisionMetricsEditField.Value = metrics.Precision;
            app.RecallMetricsEditField.Value = metrics.Recall;
            app.F1ScoreMetricsEditField.Value = metrics.F1Score;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SqueakPeekStudioUIFigure = uifigure('Visible', 'off');
            app.SqueakPeekStudioUIFigure.Name = 'Squeak Peek Studio';
            app.SqueakPeekStudioUIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'logo', 'squeakpeak_logo.png');
            app.TabGroup = uitabgroup(app.SqueakPeekStudioUIFigure);
            app.DataInputTab = uitab(app.TabGroup);
            app.DataInputTab.Title = 'Data Input';
            app.DataInputPanel = uipanel(app.DataInputTab);
            app.DataInputGrid = uigridlayout(app.DataInputPanel);
            app.LoadFilesButton = uibutton(app.DataInputGrid, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.SingleBatchModeButton = uibutton(app.DataInputGrid, 'push');
            app.SingleBatchModeButton.ButtonPushedFcn = createCallbackFcn(app, @SingleBatchModeButtonPushed, true);
            app.SingleBatchModeButton.Text = 'Single Mode';
            app.SingleModePanel = uipanel(app.DataInputGrid);
            app.SingleModeGridGrid = uigridlayout(app.SingleModePanel);
            app.SelectUSVButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectUSVButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectUSVButtonSinglePushed, true);
            app.SelectUSVButtonSingle.Text = 'Select USV (.wav)';
            app.SelectUSVFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectLabelButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectLabelButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectLabelButtonSinglePushed, true);
            app.SelectLabelButtonSingle.Text = 'Select Label (.txt)';
            app.SelectLabelFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectReferenceLabelFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectVideoFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectVideoFieldSingle.Visible = 'off';
            app.SelectVideoButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectVideoButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonSinglePushed, true);
            app.SelectVideoButtonSingle.Visible = 'off';
            app.SelectVideoButtonSingle.Text = 'Select Video (.mov)';
            app.SelectReferenceLabelButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectReferenceLabelButtonSingle.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceLabelButtonSinglePushed, true);
            app.SelectReferenceLabelButtonSingle.Text = 'Select Reference Label (.txt)';
            app.BatchModePanel = uipanel(app.DataInputGrid);
            app.BatchModeGridGrid = uigridlayout(app.BatchModePanel);
            app.SelectUSVButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectUSVButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectUSVButtonBatchPushed, true);
            app.SelectUSVButtonBatch.Text = 'Select USVs';
            app.SelectLabeltxtButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectLabeltxtButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectLabeltxtButtonBatchPushed, true);
            app.SelectLabeltxtButtonBatch.Text = 'Select Labels';
            app.SelectReferenceLabeltxtButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectReferenceLabeltxtButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceLabeltxtButtonBatchPushed, true);
            app.SelectReferenceLabeltxtButtonBatch.Text = 'Select Reference Labels';
            app.SelectVideomp4ButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectVideomp4ButtonBatch.ButtonPushedFcn = createCallbackFcn(app, @SelectVideomp4ButtonBatchPushed, true);
            app.SelectVideomp4ButtonBatch.Visible = 'off';
            app.SelectVideomp4ButtonBatch.Text = 'Select Videos';
            app.SelectLabelFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectReferenceLabelFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectVideoFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectVideoFieldBatch.Visible = 'off';
            app.SelectUSVFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectUSVFieldBatch.ValueChangedFcn = createCallbackFcn(app, @SelectUSVFieldBatchValueChanged, true);
            app.USVBatchFound = uilabel(app.BatchModeGridGrid);
            app.USVBatchFound.Text = '(x)';
            app.LabelBatchFound = uilabel(app.BatchModeGridGrid);
            app.LabelBatchFound.Text = '(x)';
            app.ReferenceLabelBatchFound = uilabel(app.BatchModeGridGrid);
            app.ReferenceLabelBatchFound.Text = '(x)';
            app.VideoBatchFound = uilabel(app.BatchModeGridGrid);
            app.VideoBatchFound.Visible = 'off';
            app.VideoBatchFound.Text = '(x)';
            app.SelectnEditFieldLabel = uilabel(app.BatchModeGridGrid);
            app.SelectnEditFieldLabel.Text = 'Select n';
            app.SelectnEditField = uieditfield(app.BatchModeGridGrid, 'numeric');
            app.SelectnEditField.Value = 1;
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.VisualizationPanel = uipanel(app.VisualizationTab);
            app.VisualizationGrid = uigridlayout(app.VisualizationPanel);
            app.WaveformPlot = uipanel(app.VisualizationGrid);
            app.WaveformGrid = uigridlayout(app.WaveformPlot);
            app.WaveformUIAxes = uiaxes(app.WaveformGrid);
            title(app.WaveformUIAxes, 'USV Waveform')
            xlabel(app.WaveformUIAxes, 'X')
            ylabel(app.WaveformUIAxes, 'Y')
            zlabel(app.WaveformUIAxes, 'Z')
            app.SpectrogramPlot = uipanel(app.VisualizationGrid);
            app.SpectrogramGrid = uigridlayout(app.SpectrogramPlot);
            app.SpectrogramUIAxes = uiaxes(app.SpectrogramGrid);
            title(app.SpectrogramUIAxes, 'USV Spectrogram')
            xlabel(app.SpectrogramUIAxes, 'X')
            ylabel(app.SpectrogramUIAxes, 'Y')
            zlabel(app.SpectrogramUIAxes, 'Z')
            app.SegmentMoveBackward = uibutton(app.VisualizationGrid, 'push');
            app.SegmentMoveBackward.ButtonPushedFcn = createCallbackFcn(app, @SegmentMoveBackwardButtonPushed, true);
            app.SegmentMoveBackward.Text = '<';
            app.SegmentMoveForward = uibutton(app.VisualizationGrid, 'push');
            app.SegmentMoveForward.ButtonPushedFcn = createCallbackFcn(app, @SegmentMoveForwardButtonPushed, true);
            app.SegmentMoveForward.Text = '>';
            app.SonifyButton = uibutton(app.VisualizationGrid, 'push');
            app.SonifyButton.Text = 'Sonify';
            app.SegmentStartsEditFieldLabel = uilabel(app.VisualizationGrid);
            app.SegmentStartsEditFieldLabel.Text = 'Segment Start (s)';
            app.SegmentStartsEditField = uieditfield(app.VisualizationGrid, 'numeric');
            app.SegmentStartsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentStartsEditFieldValueChanged, true);
            app.SegmentLengthsEditFieldLabel = uilabel(app.VisualizationGrid);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.SegmentLengthsEditField = uieditfield(app.VisualizationGrid, 'numeric');
            app.SegmentLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentLengthsEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.VisualizationGrid, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MetricsTab = uitab(app.TabGroup);
            app.MetricsTab.Title = 'Metrics';
            app.ExportPanel = uipanel(app.MetricsTab);
            app.ExportGrid = uigridlayout(app.ExportPanel);
            app.ReloadMetricsButton = uibutton(app.ExportGrid, 'push');
            app.ReloadMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadMetricsButtonPushed, true);
            app.ReloadMetricsButton.Text = 'Reload Metrics';
            app.DetectedLabelsEditField_2Label_3 = uilabel(app.ExportGrid);
            app.DetectedLabelsEditField_2Label_3.Text = 'Reference Labels (-)';
            app.ReferenceLabelsMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.ReferenceLabelsMetricsEditField.Editable = 'off';
            app.DetectedLabelsEditField_2Label_4 = uilabel(app.ExportGrid);
            app.DetectedLabelsEditField_2Label_4.Text = 'True Positives (-)';
            app.TruePositivesMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.TruePositivesMetricsEditField.Editable = 'off';
            app.FalseNegativesEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.FalseNegativesEditFieldLabel_2.Text = 'False Negatives (-)';
            app.FalseNegativesMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.FalseNegativesMetricsEditField.Editable = 'off';
            app.PrecisionEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.PrecisionEditFieldLabel_2.Text = 'Precision (%)';
            app.PrecisionMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.PrecisionMetricsEditField.Editable = 'off';
            app.RecallEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.RecallEditFieldLabel_2.Text = 'Recall (%)';
            app.RecallMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.RecallMetricsEditField.Editable = 'off';
            app.F1ScoreEditFieldLabel_2 = uilabel(app.ExportGrid);
            app.F1ScoreEditFieldLabel_2.Text = 'F1 Score (-)';
            app.F1ScoreMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.F1ScoreMetricsEditField.Editable = 'off';
            app.DetectedLabelsEditField_5Label = uilabel(app.ExportGrid);
            app.DetectedLabelsEditField_5Label.Text = 'Detected Labels (-)';
            app.DetectedLabelsMetricsEditField = uieditfield(app.ExportGrid, 'numeric');
            app.DetectedLabelsMetricsEditField.Editable = 'off';
            app.DetectionmetricsLabel = uilabel(app.ExportGrid);
            app.DetectionmetricsLabel.Text = 'Detection metrics';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.DetectionPanel = uipanel(app.DetectionTab);
            app.DetectionGrid = uigridlayout(app.DetectionPanel);
            app.RunDetectorsButton = uibutton(app.DetectionGrid, 'push');
            app.RunDetectorsButton.ButtonPushedFcn = createCallbackFcn(app, @RunDetectorsButtonPushed, true);
            app.RunDetectorsButton.Text = 'Run Detectors';
            app.DetectorsListBoxLabel = uilabel(app.DetectionGrid);
            app.DetectorsListBoxLabel.Text = 'Detectors';
            app.UsedDetectorsListBox = uilistbox(app.DetectionGrid);
            app.UsedDetectorsListBox.Items = {'PSD', 'BSCD', 'RBD'};
            app.UsedDetectorsListBox.Multiselect = 'on';
            app.UsedDetectorsListBox.Value = {'PSD'};
            app.SelectDetectorsExportFolder = uibutton(app.DetectionGrid, 'push');
            app.SelectDetectorsExportFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectDetectorsExportFolderButtonPushed, true);
            app.SelectDetectorsExportFolder.Text = 'Select Export Folder';
            app.SelectDetectorsExportFolderEditField = uieditfield(app.DetectionGrid, 'text');
            app.SelectDetectorsExportFolderEditField.ValueChangedFcn = createCallbackFcn(app, @SelectDetectorsExportFolderEditFieldValueChanged, true);
            app.PostProcessingLabel = uilabel(app.DetectionGrid);
            app.PostProcessingLabel.Text = 'Post–processing';
            app.UsedPostProcessingListBox = uilistbox(app.DetectionGrid);
            app.UsedPostProcessingListBox.Items = {'None', 'Merge Close Labels', 'Remove Short Labels'};
            app.UsedPostProcessingListBox.Multiselect = 'on';
            app.UsedPostProcessingListBox.Value = {'Merge Close Labels'};
            app.LabelEditTab = uitab(app.TabGroup);
            app.LabelEditTab.Title = 'Label Edit';
            app.LabelEditPanel = uipanel(app.LabelEditTab);
            app.LabelEditGrid = uigridlayout(app.LabelEditPanel);
            app.LabelEditSpectrogramUIAxes = uiaxes(app.LabelEditGrid);
            title(app.LabelEditSpectrogramUIAxes, 'USV Spectrogram')
            xlabel(app.LabelEditSpectrogramUIAxes, 'X')
            ylabel(app.LabelEditSpectrogramUIAxes, 'Y')
            zlabel(app.LabelEditSpectrogramUIAxes, 'Z')
            app.AcceptButton = uibutton(app.LabelEditGrid, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.RejectButton = uibutton(app.LabelEditGrid, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.RejectButton.Text = 'Reject';
            app.LabelEditMoveBackward = uibutton(app.LabelEditGrid, 'push');
            app.LabelEditMoveBackward.ButtonPushedFcn = createCallbackFcn(app, @LabelEditMoveBackwardButtonPushed, true);
            app.LabelEditMoveBackward.Text = '<';
            app.LabelEditMoveForward = uibutton(app.LabelEditGrid, 'push');
            app.LabelEditMoveForward.ButtonPushedFcn = createCallbackFcn(app, @LabelEditMoveForwardButtonPushed, true);
            app.LabelEditMoveForward.Text = '>';
            app.EditFieldLabel = uilabel(app.LabelEditGrid);
            app.EditFieldLabel.Text = '#';
            app.LabelEditCurrentLabel = uieditfield(app.LabelEditGrid, 'numeric');
            app.LabelEditCurrentLabel.ValueChangedFcn = createCallbackFcn(app, @LabelEditCurrentLabelValueChanged, true);
            app.LabelEditCurrentLabel.Value = 1;
            app.Label_3 = uilabel(app.LabelEditGrid);
            app.Label_3.Text = '/';
            app.LabelEditAllLabels = uieditfield(app.LabelEditGrid, 'numeric');
            app.LabelEditAllLabels.Editable = 'off';
            app.LoadLabelEditButton = uibutton(app.LabelEditGrid, 'push');
            app.LoadLabelEditButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLabelEditButtonPushed, true);
            app.LoadLabelEditButton.Text = 'Load Label';
            app.LabelClassificationEditFieldLabel = uilabel(app.LabelEditGrid);
            app.LabelClassificationEditFieldLabel.Text = 'Label Classification';
            app.LabelClassificationEditField = uieditfield(app.LabelEditGrid, 'text');
            app.LabelClassificationEditField.ValueChangedFcn = createCallbackFcn(app, @LabelClassificationEditFieldValueChanged, true);
            app.ExportLabelsButton = uibutton(app.LabelEditGrid, 'push');
            app.ExportLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLabelsButtonPushed, true);
            app.ExportLabelsButton.Text = 'Export';
            app.VideoSyncTab = uitab(app.TabGroup);
            app.VideoSyncTab.Title = 'Video Sync';
            app.VideoSyncPanel = uipanel(app.VideoSyncTab);
            app.VideoSyncGrid = uigridlayout(app.VideoSyncPanel);
            app.SelectVideoFieldSingle_2 = uieditfield(app.VideoSyncGrid, 'text');
            app.SelectVideoFieldSingle_2.ValueChangedFcn = createCallbackFcn(app, @SelectVideoButtonSinglePushed, true);
            app.SelectVideoButtonSingle_2 = uibutton(app.VideoSyncGrid, 'push');
            app.SelectVideoButtonSingle_2.Text = 'Select Video (.mov)';
            app.SynchronizationTimeinVideoEditFieldLabel = uilabel(app.VideoSyncGrid);
            app.SynchronizationTimeinVideoEditFieldLabel.Text = 'Synchronization Time in Video';
            app.SynchronizationTimeinVideoEditField = uieditfield(app.VideoSyncGrid, 'numeric');
            app.sLabel = uilabel(app.VideoSyncGrid);
            app.sLabel.Text = '(s)';
            app.SelectDetectorsExportFolder_2 = uibutton(app.VideoSyncGrid, 'push');
            app.SelectDetectorsExportFolder_2.Text = 'Select Export Folder';
            app.SelectDetectorsExportFolderEditField_2 = uieditfield(app.VideoSyncGrid, 'text');
            app.SelectUSVButtonSingle_2 = uibutton(app.VideoSyncGrid, 'push');
            app.SelectUSVButtonSingle_2.Text = 'Select USV (.wav)';
            app.SelectUSVFieldSingle_2 = uieditfield(app.VideoSyncGrid, 'text');
            app.ExportLabelsButton_2 = uibutton(app.VideoSyncGrid, 'push');
            app.ExportLabelsButton_2.Text = 'Synchronize';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanel = uipanel(app.SettingsTab);
            app.SettingsGrid = uigridlayout(app.SettingsPanel);
            app.SaveSettingsButton = uibutton(app.SettingsGrid, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.SettingsGrid, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SettingsInsideTab = uitabgroup(app.SettingsGrid);
            app.DataInputSettingsTab = uitab(app.SettingsInsideTab);
            app.DataInputSettingsTab.Title = 'Data Input';
            app.DataInputSettingsGrid = uigridlayout(app.DataInputSettingsTab);
            app.DetectormodeLabel = uilabel(app.DataInputSettingsGrid);
            app.DetectormodeLabel.Text = 'Detector mode';
            app.SingleBatchModeButton_2 = uibutton(app.DataInputSettingsGrid, 'push');
            app.SingleBatchModeButton_2.ButtonPushedFcn = createCallbackFcn(app, @SingleBatchModeButtonPushed, true);
            app.SingleBatchModeButton_2.Text = 'Single Mode';
            app.VisualizationSettingsTab = uitab(app.SettingsInsideTab);
            app.VisualizationSettingsTab.Title = 'Visualization';
            app.VisualizationSettingsGrid = uigridlayout(app.VisualizationSettingsTab);
            app.ShowLoadingDialogueCheckBox = uicheckbox(app.VisualizationSettingsGrid);
            app.ShowLoadingDialogueCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLoadingDialogueCheckBoxValueChanged, true);
            app.ShowLoadingDialogueCheckBox.Text = 'Show Loading Dialogue';
            app.SpectrogramWindowSizeEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramWindowSizeEditFieldLabel.Text = 'Spectrogram Window Size';
            app.SpectrogramWindowSizeEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramWindowSizeEditFieldValueChanged, true);
            app.SpectrogramWindowSizeEditField.Value = 1;
            app.SpectrogramWindowOverlapEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramWindowOverlapEditFieldLabel.Text = 'Spectrogram Window Overlap';
            app.SpectrogramWindowOverlapEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramWindowOverlapEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramWindowOverlapEditFieldValueChanged, true);
            app.SpectrogramWindowOverlapEditField.Value = 1;
            app.SpectrogramMinimumFrequencyEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramMinimumFrequencyEditFieldLabel.Text = 'Spectrogram Minimum Frequency';
            app.SpectrogramMinimumFrequencyEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramMinimumFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramMinimumFrequencyEditFieldValueChanged, true);
            app.SpectrogramMinimumFrequencyEditField.Value = 1;
            app.SpectrogramMaximumFrequencyEditFieldLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramMaximumFrequencyEditFieldLabel.Text = 'Spectrogram Maximum Frequency';
            app.SpectrogramMaximumFrequencyEditField = uieditfield(app.VisualizationSettingsGrid, 'numeric');
            app.SpectrogramMaximumFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrogramMaximumFrequencyEditFieldValueChanged, true);
            app.SpectrogramMaximumFrequencyEditField.Value = 1;
            app.Label = uilabel(app.VisualizationSettingsGrid);
            app.Label.Text = '(-)';
            app.Label_2 = uilabel(app.VisualizationSettingsGrid);
            app.Label_2.Text = '(-)';
            app.kHzLabel = uilabel(app.VisualizationSettingsGrid);
            app.kHzLabel.Text = '(kHz)';
            app.kHzLabel_2 = uilabel(app.VisualizationSettingsGrid);
            app.kHzLabel_2.Text = '(kHz)';
            app.ShowLabelsCheckBox = uicheckbox(app.VisualizationSettingsGrid);
            app.ShowLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLabelsCheckBoxValueChanged, true);
            app.ShowLabelsCheckBox.Text = 'Show Labels';
            app.ShowReferenceLabelsCheckBox = uicheckbox(app.VisualizationSettingsGrid);
            app.ShowReferenceLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowReferenceLabelsCheckBoxValueChanged, true);
            app.ShowReferenceLabelsCheckBox.Text = 'Show Reference Labels';
            app.SpectrogramColormapDropDownLabel = uilabel(app.VisualizationSettingsGrid);
            app.SpectrogramColormapDropDownLabel.Text = 'Spectrogram Colormap';
            app.SpectrogramColormapDropDown = uidropdown(app.VisualizationSettingsGrid);
            app.SpectrogramColormapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet'};
            app.SpectrogramColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectrogramColormapDropDownValueChanged, true);
            app.SpectrogramColormapDropDown.Value = 'parula';
            app.LabelColorDropDownLabel = uilabel(app.VisualizationSettingsGrid);
            app.LabelColorDropDownLabel.Text = 'Label Color';
            app.LabelColorDropDown = uidropdown(app.VisualizationSettingsGrid);
            app.LabelColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white'};
            app.LabelColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelColorDropDownValueChanged, true);
            app.LabelColorDropDown.Value = 'red';
            app.LabelColorDropDown_2Label = uilabel(app.VisualizationSettingsGrid);
            app.LabelColorDropDown_2Label.Text = 'Reference Label Color';
            app.ReferenceLabelColorDropDown = uidropdown(app.VisualizationSettingsGrid);
            app.ReferenceLabelColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white'};
            app.ReferenceLabelColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceLabelColorDropDownValueChanged, true);
            app.ReferenceLabelColorDropDown.Value = 'red';
            app.DetectionSettingsTab = uitab(app.SettingsInsideTab);
            app.DetectionSettingsTab.Title = 'Detection';
            app.DetectionSettingsGrid = uigridlayout(app.DetectionSettingsTab);
            app.LabelEditSettingsTab = uitab(app.SettingsInsideTab);
            app.LabelEditSettingsTab.Title = 'Label Edit';
            app.LabelEditSettingsGrid = uigridlayout(app.LabelEditSettingsTab);
            app.SpectrogramWindowOverlapEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramWindowOverlapEditField_2Label.Text = 'Spectrogram Window Overlap';
            app.SpectrogramLEWindowOverlapEditField = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramLEWindowOverlapEditField.Value = 1;
            app.SpectrogramMaximumFrequencyEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramMaximumFrequencyEditField_2Label.Text = 'Spectrogram Maximum Frequency';
            app.SpectrogramLEMaximumFrequencyEditField = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramLEMaximumFrequencyEditField.Value = 1;
            app.SpectrogramWindowSizeEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramWindowSizeEditField_2Label.Text = 'Spectrogram Window Size';
            app.SpectrogramLEWindowSizeEditField = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramLEWindowSizeEditField.Value = 1;
            app.SpectrogramMinimumFrequencyEditField_2Label = uilabel(app.LabelEditSettingsGrid);
            app.SpectrogramMinimumFrequencyEditField_2Label.Text = 'Spectrogram Minimum Frequency';
            app.SpectrogramLEMinimumFrequencyEditField = uieditfield(app.LabelEditSettingsGrid, 'numeric');
            app.SpectrogramLEMinimumFrequencyEditField.Value = 1;
            app.VideoSyncSettingsTab = uitab(app.SettingsInsideTab);
            app.VideoSyncSettingsTab.Title = 'Video Sync';
            app.VideoSyncSettingsGrid = uigridlayout(app.VideoSyncSettingsTab);
            app.ExportSettingsTab = uitab(app.SettingsInsideTab);
            app.ExportSettingsTab.Title = 'Metrics';
            app.ExportSettingsGrid = uigridlayout(app.ExportSettingsTab);
            app.OtherSettingsTab = uitab(app.SettingsInsideTab);
            app.OtherSettingsTab.Title = 'Other';
            app.OtherSettingsGrid = uigridlayout(app.OtherSettingsTab);
            app.ThemeDropDownLabel = uilabel(app.OtherSettingsGrid);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.OtherSettingsGrid);
            app.ThemeDropDown.Items = {'Light', 'Dark'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Value = 'Light';
            app.SettingsMenuPanel = uipanel(app.SettingsTab);
            app.SettingsMenuGrid = uigridlayout(app.SettingsMenuPanel);
            app.SettingsDropDownLabel = uilabel(app.SettingsMenuGrid);
            app.SettingsDropDownLabel.Text = 'Settings';
            app.SettingsDropDown = uidropdown(app.SettingsMenuGrid);
            app.SettingsDropDown.Items = {'Data Input', 'Visualization', 'Metrics', 'Detection', 'Label Edit', 'Video Sync', 'Other'};
            app.SettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @SettingsDropDownValueChanged, true);
            app.SettingsDropDown.Value = 'Data Input';
            app.SettingsText = uilabel(app.SettingsMenuGrid);
            app.SettingsText.Text = '';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.InfoPanel = uipanel(app.InfoTab);
            app.InfoGrid = uigridlayout(app.InfoPanel);
            app.Image = uiimage(app.InfoGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'squeakpeak_logo.png');
            app.NameOfAppLabel = uilabel(app.InfoGrid);
            app.NameOfAppLabel.Text = 'Squeak Peek Studio';
            app.DescriptionLabel = uilabel(app.InfoGrid);
            app.DescriptionLabel.Text = 'Software for Visualization, Segmentation, and Sonification of Ultrasonic Vocalizations of Laboratory Rats ';
            app.Text1Label = uilabel(app.InfoGrid);
            app.Text1Label.Text = 'This application was developed as part of a Master’s Thesis in Electronics and Communications - Audiovisual Technology and Signal';
            app.Text2Label = uilabel(app.InfoGrid);
            app.Text2Label.Text = 'processing at the Czech Technical University in Prague, Faculty of Electrical Engineering (CTU FEE) in collaboration with National Institute of';
            app.Text3Label = uilabel(app.InfoGrid);
            app.Text3Label.Text = 'Mental Health in Klecany, Czech Republic.';
            app.AuthorLabel = uilabel(app.InfoGrid);
            app.AuthorLabel.Text = 'Author';
            app.AuthorNameLabel = uilabel(app.InfoGrid);
            app.AuthorNameLabel.Text = 'Antonín Gazda';
            app.SupervisorLabel = uilabel(app.InfoGrid);
            app.SupervisorLabel.Text = 'Supervisor';
            app.SupervisorNameLabel = uilabel(app.InfoGrid);
            app.SupervisorNameLabel.Text = 'prof. Ing. Roman Čmejla, CSc.';
            app.AcademicYearLabel = uilabel(app.InfoGrid);
            app.AcademicYearLabel.Text = 'Academic Year';
            app.YearLabel = uilabel(app.InfoGrid);
            app.YearLabel.Text = '2024/2025';
            app.AuthorDepartmentLabel = uilabel(app.InfoGrid);
            app.AuthorDepartmentLabel.Text = 'Department of Radioelectronics';
            app.SupervisorDepartmentLabel = uilabel(app.InfoGrid);
            app.SupervisorDepartmentLabel.Text = 'Department of Circuit Theory';
            app.OpenDocumentationButton = uibutton(app.InfoGrid, 'push');
            app.OpenDocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDocumentationButtonPushed, true);
            app.OpenDocumentationButton.Text = 'Open Documentation';
            app.OpenMastersThesisButton = uibutton(app.InfoGrid, 'push');
            app.OpenMastersThesisButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMastersThesisButtonPushed, true);
            app.OpenMastersThesisButton.Text = 'Open Master''s Thesis';
            app.OpenSourceCodeButton = uibutton(app.InfoGrid, 'push');
            app.OpenSourceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSourceCodeButtonPushed, true);
            app.OpenSourceCodeButton.Text = 'Open Source Code';
            app.MatlabVersionLabel = uilabel(app.InfoGrid);
            app.MatlabVersionLabel.Text = 'MATLAB version';
            app.ApplicationVersionLabel = uilabel(app.InfoGrid);
            app.ApplicationVersionLabel.Text = 'Application version';
            app.MatlabVersion = uilabel(app.InfoGrid);
            app.MatlabVersion.Text = '23.2.0.2380103 (R2023b) Update 1';
            app.AppVersion = uilabel(app.InfoGrid);
            app.AppVersion.Text = 'version defined at runtime';
            app.AuthorEmailHyperlink = uihyperlink(app.InfoGrid);
            app.AuthorEmailHyperlink.URL = 'mailto:me@antoningazda.com';
            app.AuthorEmailHyperlink.Text = {'me@antoningazda.com'; ''};
            app.NIMHLogo = uiimage(app.InfoGrid);
            app.NIMHLogo.URL = 'https://www.nudz.cz/';
            app.NIMHLogo.AltText = 'NIMH LOGO';
            app.NIMHLogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'nimh_logo.png');
            app.CTULogo = uiimage(app.InfoGrid);
            app.CTULogo.URL = 'https://fel.cvut.cz';
            app.CTULogo.AltText = 'CTU FEE LOGO';
            app.CTULogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'fee_logo.jpg');
            app.ButtonsMenuPanel = uipanel(app.SqueakPeekStudioUIFigure);
            app.ButtonsMenuGrid = uigridlayout(app.ButtonsMenuPanel);
            app.DataInputButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DataInputButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DataInputButton.Text = 'Data Input';
            app.DetectionButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DetectionButton.Text = 'Detection';
            app.LabelEditButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.LabelEditButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.LabelEditButton.Text = 'Label Edit';
            app.VideoSyncButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VideoSyncButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VideoSyncButton.Text = 'Video Sync';
            app.SettingsButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.SettingsButton.Text = 'Settings';
            app.InfoButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.InfoButton.Text = 'Info';
            app.MetricsButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.MetricsButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.MetricsButton.Text = 'Metrics';
            app.VisualizationButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VisualizationButton.Text = 'Visualization';
            % Show the figure after all components are created
            app.SqueakPeekStudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SqueakPeekStudio

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SqueakPeekStudioUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SqueakPeekStudioUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SqueakPeekStudioUIFigure)
        end
    end
end